classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FreqRangeSlider                matlab.ui.control.RangeSlider
        FreqRangeSliderLabel           matlab.ui.control.Label
        FilterFrequencyResponseLabel   matlab.ui.control.Label
        GenerateTriangleWaveCheckBox   matlab.ui.control.CheckBox
        FiltersDropDown                matlab.ui.control.DropDown
        FiltersDropDownLabel           matlab.ui.control.Label
        PlayModifiedSignalSoundButton  matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PlayOriginalSignalSoundButton  matlab.ui.control.Button
        DelayKnob                      matlab.ui.control.Knob
        DelayKnobLabel                 matlab.ui.control.Label
        FrequencySlider                matlab.ui.control.Slider
        FrequencySliderLabel           matlab.ui.control.Label
        PhaseSlider                    matlab.ui.control.Slider
        PhaseSliderLabel               matlab.ui.control.Label
        Wave1DropDown                  matlab.ui.control.DropDown
        Wave1DropDownLabel             matlab.ui.control.Label
        SoundGeneratorsLabel           matlab.ui.control.Label
        PitchSlider                    matlab.ui.control.Slider
        PitchSliderLabel               matlab.ui.control.Label
        VolumeSlider                   matlab.ui.control.Slider
        VolumeSliderLabel              matlab.ui.control.Label
        SignalSelection                matlab.ui.control.DropDown
        SignalSoundFilesLabel          matlab.ui.control.Label
        graph_FR_P                     matlab.ui.control.UIAxes
        graph_FR_M                     matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        graph_FD                       matlab.ui.control.UIAxes
        graph_TD                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        modifiedSignal = [];
        modifiedSignal_Fs = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SignalSelection
        function SignalSelectionValueChanged(app, event)
            value = app.SignalSelection.Value;
            if value == "Sin Wave"
                f = 10;                 % Signal frequency (Hz)
                phase_offset = 0;       % Initial phase offset in radians
                freq_offset = 0;        % Initial frequency offset in Hz
                Fs = 1000;              % Sampling frequency (Hz)

                [t, x, x_phase_offset, x_freq_offset] = gen_offset(f, phase_offset, freq_offset, Fs);
                
                % Graph in Time Domain (Phase Offset)
                plot(app.graph_TD, t, x_phase_offset)
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Time Domain (Phase Offset)');

                % Graph in Time Domain (Frequency Offset)
                plot(app.graph_FD, t, x_freq_offset)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Frequency Offset)');
            end

            if value == "Sad Valentine by No Vacation"
                % Original signal and sampling frequency
                [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                app.modifiedSignal = your_sound;
                app.modifiedSignal_Fs = Fs_sound;
                
                % Time vector for original signal
                t_sound = linspace(0, length(your_sound) / Fs_sound, length(your_sound));
                
                % Plot the original signal in the time domain
                plot(app.graph_TD, t_sound, your_sound);
                title(app.graph_TD, 'Signal in Time Domain');
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                
                % Perform Fourier Transform to get frequency content
                N = length(your_sound);  % Length of the signal
                frequencies = (0:N-1) * Fs_sound / N;  % Frequency vector
                signal_fft = fft(your_sound);  % Compute FFT of the signal
                
                % Plot the magnitude of the signal in the frequency domain
                plot(app.graph_FD, frequencies, abs(signal_fft));
                title(app.graph_FD, 'Magnitude Spectrum of Signal');
                xlabel(app.graph_FD, 'Frequency (Hz)');
                ylabel(app.graph_FD, 'Magnitude');
            end
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            if app.SignalSelection.Value == "Sin Wave"
                f = 10
                Fs = 1000
    
                phase_offset = app.PhaseSlider.Value;
                [t, ~, x_phase_offset, ~] = gen_offset(f, phase_offset, 0, Fs);
                plot(app.graph_TD, t, x_phase_offset)
            end
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            if app.SignalSelection.Value == "Sin Wave"
                f = 10
                Fs = 1000
    
                freq_offset = app.FrequencySlider.Value;
                [t, ~, ~, x_freq_offset] = gen_offset(f, 0, freq_offset, Fs);
                plot(app.graph_FD, t, x_freq_offset)
            end
        end

        % Button pushed function: PlayOriginalSignalSoundButton
        function PlayOriginalSignalSoundButtonPushed(app, event)
            clear sound; % Stop sound from playing
            if app.SignalSelection.Value == "Sin Wave"
                samplingRate = 44100;  % Sampling rate (Hz)
                duration = 3;
                frequency = 440;       % Frequency of the sine wave (Hz)
                t = linspace(0, duration, duration * samplingRate);
                sineWave = sin(2 * pi * frequency * t);
                sound(sineWave, samplingRate);
            end


            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                [your_sound, Fs_sound] = audioread('sadvalentine.wav'); % Update the file name as needed
                sound(your_sound * app.VolumeSlider.Value, Fs_sound)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound; % Stop sound from playing
        end

        % Button pushed function: PlayModifiedSignalSoundButton
        function PlayModifiedSignalSoundButtonPushed(app, event)
            clear sound; % Stop sound from playing
            if app.SignalSelection.Value == "Sin Wave"
                if app.FiltersDropDown.Value == "Chipmunk Filter"
                        samplingRate = 44100;  % Sampling rate (Hz)
                        frequency = 440;       % Frequency of the sine wave (Hz)
                        freq_offset = app.FrequencySlider.Value;
                        [~, ~, ~, x_freq_offset] = gen_offset(frequency, 0, freq_offset, samplingRate);
                        sound(x_freq_offset, samplingRate*2);
                    return
                end 

                if app.FiltersDropDown.Value == "Slow + Reverb Filter"
                    samplingRate = 44100;  % Sampling rate (Hz)
                    frequency = 440;       % Frequency of the sine wave (Hz)
    
                    freq_offset = app.FrequencySlider.Value;
                    [~, ~, ~, x_freq_offset] = gen_offset(frequency, 0, freq_offset, samplingRate);
                    filteredSignal = gen_highPitch(x_freq_offset, samplingRate);
                    sound(filteredSignal, 44100/2)
                    return
                end


                samplingRate = 44100;  % Sampling rate (Hz)
                frequency = 440;       % Frequency of the sine wave (Hz)
                freq_offset = app.FrequencySlider.Value;
                [~, ~, ~, x_freq_offset] = gen_offset(frequency, 0, freq_offset, samplingRate);
                sound(x_freq_offset, samplingRate);
            end

            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                if app.FiltersDropDown.Value == "Chipmunk Filter"
                    originalSignal = audioread('sadvalentine.wav'); % Provide your audio file
                    factor = 2.0;
                    filteredSignal = gen_highPitch(originalSignal, factor);
                    sound(filteredSignal, 44100)
                    return
                end

                if app.FiltersDropDown.Value == "Slow + Reverb Filter"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    factor = 0.25; % Slow down by 50%
                    reverberationTime = 1.0; % One second reverberation time
                    filteredSignal = gen_slowReverb(your_sound, factor, reverberationTime);
                    sound(filteredSignal, 44100)
                    return
                end
                if app.FiltersDropDown.Value == "Bandpass"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandpass1(freq1,freq2,'sadvalentine.wav');
                    sound(filteredSignal, Fs_sound)
                    return
                end
                if app.FiltersDropDown.Value == "Bandstop"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandpass1(freq1,freq2,'sadvalentine.wav');
                    sound(filteredSignal, Fs_sound)
                    return
                end
                if app.FiltersDropDown.Value == "Enhance"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    z=app.VolumeSlider.Value;
                    filteredSignal = enhance1(freq1,freq2,z,'sadvalentine.wav');
                    sound(real(filteredSignal), Fs_sound)
                    return
                end
                % SIGNAL MODIFICATIONS
                delay_value = app.DelayKnob.Value;
                pitch_value = app.PitchSlider.Value;
                copy = app.modifiedSignal;
                copy_fs = app.modifiedSignal_Fs;


                % Pitch
                if pitch_value ~= 0
                    copy = shiftPitch(copy, pitch_value); 
                end


                % Delay
                if delay_value ~= 0
                    initial_feedback_gain = 0.5;
                    copy = gen_delay(copy, copy_fs, delay_value, initial_feedback_gain);
                end


                % PLAYING MODIFIED SOUND WITH ALL MODS :)
                sound(copy * app.VolumeSlider.Value, copy_fs)
            end

        end

        % Value changed function: DelayKnob
        function DelayKnobValueChanged(app, event)
            delay_time = app.DelayKnob.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copied_signal = app.modifiedSignal;
                copied_signal_fs = app.modifiedSignal_Fs;
                initial_feedback_gain = 0.5;
                copied_signal = gen_delay(copied_signal, copied_signal_fs, delay_time, initial_feedback_gain);
                t_sound = linspace(0, length(copied_signal) / copied_signal_fs, length(copied_signal));
                plot(app.graph_FD, t_sound, copied_signal)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Delayed Signal)');
            end
        end

        % Callback function: FiltersDropDown, FreqRangeSlider, VolumeSlider
        function FiltersDropDownValueChanged(app, event)
            value = app.FiltersDropDown.Value;
            if value == "Chipmunk Filter"
                if app.SignalSelection.Value == "Sin Wave"
                    samplingRate = 44100;  % Sampling rate (Hz)
                    frequency = 440;       % Frequency of the sine wave (Hz)
    
                    freq_offset = app.FrequencySlider.Value;
                    [~, origSignal, ~, x_freq_offset] = gen_offset(frequency, 0, freq_offset, samplingRate);
                    filteredSignal = gen_highPitch(x_freq_offset, samplingRate);
                    plot(app.graph_FD,filteredSignal); % Plot the filtered signal
                    title(app.graph_FD, 'Filtered Signal - Chipmunk'); % Set the title
                    xlabel(app.graph_FD,'Time'); % Set the x-axis label
                    ylabel(app.graph_FD, 'Amplitude'); % Set the y-axis label

                    % Plot the Filter Frequency Response graphs for sine wave
                    plot_freqRes(origSignal, filteredSignal, samplingRate, app.graph_FR_M, app.graph_FR_P);

                end

                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    filteredSignal = gen_highPitch(your_sound, Fs_sound);
                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Chipmunk Filter');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');   
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end

            if value == "Slow + Reverb Filter"

                if app.SignalSelection.Value == "Sin Wave"
                    samplingRate = 44100;  % Sampling rate (Hz)
                    frequency = 440;       % Frequency of the sine wave (Hz)
                    freq_offset = app.FrequencySlider.Value;
                    [~, origSignal, ~, x_freq_offset] = gen_offset(frequency, 0, freq_offset, samplingRate);
                    factor = 0.25; % Slow down by 50%
                    reverberationTime = 1.0; % One second reverberation time
                    filteredSignal = gen_slowReverb(x_freq_offset, factor, reverberationTime);
                    plot(app.graph_FD,filteredSignal); % Plot the filtered signal
                    title(app.graph_FD, 'Filtered Signal - Slow + Reverb'); % Set the title
                    xlabel(app.graph_FD,'Time'); % Set the x-axis label
                    ylabel(app.graph_FD, 'Amplitude'); % Set the y-axis label

                    % Plot the Filter Frequency Response graphs for sine wave
                    plot_freqRes(origSignal, filteredSignal, samplingRate, app.graph_FR_M, app.graph_FR_P);
                end

                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    factor = 0.25; % Slow down by 50%
                    reverberationTime = 1.0; % One second reverberation time
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    filteredSignal = gen_slowReverb(your_sound, factor, reverberationTime);
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain (Slow + Reverb Filter)');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);


                end
            end
             value = app.FiltersDropDown.Value;
            if value == "Bandpass"

                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandpass1(freq1,freq2,'sadvalentine.wav');
                    
                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Bandpass');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');   
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "Bandstop"

                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    selectedRange =app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandstop1(freq1,freq2,'sadvalentine.wav');

                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Bandstop');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');   
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "Enhance"

                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                        selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    z=app.VolumeSlider.Value;
                    filteredSignal = enhance1(freq1,freq2,z,'sadvalentine.wav');

                    % Time vector for filtered signal
                 
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, real(filteredSignal), 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Enhance');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');   
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "--None--"
                cla(app.graph_FD);

            end
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            clear sound; % Stop sound from playing

            value = app.PitchSlider.Value;
           
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy = app.modifiedSignal;
                
                output_wave = shiftPitch(copy, value); 
                % Display the changed pitch
                t_sound = linspace(0, length(output_wave) / app.modifiedSignal_Fs, length(output_wave));
                plot(app.graph_FD, t_sound, output_wave);
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Signal)');
            end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                % Call the ValueChanged function of GenerateTriangleWaveCheckBox
                GenerateTriangleWaveCheckBoxValueChanged(app);
        end

        % Value changed function: GenerateTriangleWaveCheckBox
        function GenerateTriangleWaveCheckBoxValueChanged(app, event)
            % Check the state of the checkbox
            isChecked = app.GenerateTriangleWaveCheckBox.Value;
            
            if isChecked
                % If the checkbox is checked, generate and plot the triangle waveform
                % Generate the triangle waveform
                Fs = 44100;  % Sampling frequency
                dur = 1;     % Duration in seconds
                % Get frequency and phase from sliders
                frequency = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;

                % Generate the triangle waveform using a piecewise function
                triangleWaveform = gen_triangleWaveform(1, frequency, phase, Fs, dur, 0);

                % Generate time vector based on the waveform length
                t = linspace(0, dur, length(triangleWaveform));
                
                % Clear the custom axes
                cla(app.UIAxes);

                % Plot the triangle waveform on the custom axes
                plot(app.UIAxes, t, triangleWaveform);
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Triangle Waveform');
            else
                % If the checkbox is unchecked, clear the custom axes
                cla(app.UIAxes);
            end
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy_signal = value * app.modifiedSignal;

                plot(app.graph_TD,app.modifiedSignal);
    
                % Perform Fourier Transform to get frequency content
                N = length(copy_signal);  % Length of the signal
                frequencies = (0:N-1) * app.modifiedSignal_Fs / N;  % Frequency vector
                signal_fft = fft(copy_signal);  % Compute FFT of the signal
                    
                % Plot the magnitude of the signal in the frequency domain
                plot(app.graph_FD, frequencies, abs(signal_fft));
                title(app.graph_FD, 'Magnitude Spectrum of Signal');
                xlabel(app.graph_FD, 'Frequency (Hz)');
                ylabel(app.graph_FD, 'Magnitude');
            end
        end

        % Value changed function: Wave1DropDown
        function Wave1DropDownValueChanged(app, event)
            value = app.Wave1DropDown.Value;
            
            %Updated for Sine, Cosine, Sawtooth, and Square by Collin
            %Walmsley 3/13/2024
            
            if (value == "Sine")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sine

                %Generate Sine
                sineWaveform = gen_sine(amp, freq, phase, fs, dur, duty);
                
                % time plot for graph
                t = linspace(0, dur, length(sineWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                sineFD = fft(sineWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, sineWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Sine Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(sineFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Sine Waveform Frequency');

            elseif (value == "Cosine")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sine

                %Generate Cosine
                cosineWaveform = gen_cosine(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(cosineWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                cosineFD = fft(cosineWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, cosineWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Cosine Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(cosineFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Cosine Waveform Frequency');


            elseif (value == "Sawtooth")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sawtooth

                %Generate Sawtooth
                sawtoothWaveform = gen_sawtooth(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(sawtoothWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                sawFD = fft(sawtoothWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, sawtoothWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Sawtooth Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(sawFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Sawtooth Waveform Frequency');

            elseif (value == "Square")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 50;   %0-100 for percent of wave in a period is HIGH

                %Generate Square
                squareWaveform = gen_square(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(squareWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                squareFD = fft(squareWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, squareWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Square Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(squareFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Square Waveform Frequency');

            else
                cla(app.graph_TD);
                cla(app.graph_FD);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph_TD = uiaxes(app.UIFigure);
            title(app.graph_TD, 'Time Domain')
            xlabel(app.graph_TD, 'X')
            ylabel(app.graph_TD, 'Y')
            zlabel(app.graph_TD, 'Z')
            app.graph_FD = uiaxes(app.UIFigure);
            title(app.graph_FD, 'Frequency Domain')
            xlabel(app.graph_FD, 'X')
            ylabel(app.graph_FD, 'Y')
            zlabel(app.graph_FD, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Generate Triangle Wave')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.graph_FR_M = uiaxes(app.UIFigure);
            title(app.graph_FR_M, 'Magnitude')
            xlabel(app.graph_FR_M, 'Frequency')
            ylabel(app.graph_FR_M, 'Magnitude')
            zlabel(app.graph_FR_M, 'Z')
            app.graph_FR_P = uiaxes(app.UIFigure);
            title(app.graph_FR_P, 'Phase')
            xlabel(app.graph_FR_P, 'Frequency')
            ylabel(app.graph_FR_P, 'Phase')
            zlabel(app.graph_FR_P, 'Z')
            app.SignalSoundFilesLabel = uilabel(app.UIFigure);
            app.SignalSoundFilesLabel.Text = 'Signal/Sound Files';
            app.SignalSelection = uidropdown(app.UIFigure);
            app.SignalSelection.Items = {'Select', 'Sin Wave', 'Sad Valentine by No Vacation'};
            app.SignalSelection.ValueChangedFcn = createCallbackFcn(app, @SignalSelectionValueChanged, true);
            app.SignalSelection.Value = 'Select';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.VolumeSlider.Value = 0.5;
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-5 5];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.PitchSlider.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.SoundGeneratorsLabel = uilabel(app.UIFigure);
            app.SoundGeneratorsLabel.Text = 'Sound Generators:';
            app.Wave1DropDownLabel = uilabel(app.UIFigure);
            app.Wave1DropDownLabel.Text = 'Wave 1';
            app.Wave1DropDown = uidropdown(app.UIFigure);
            app.Wave1DropDown.Items = {'none', 'Sine', 'Cosine', 'Sawtooth', 'Square'};
            app.Wave1DropDown.ValueChangedFcn = createCallbackFcn(app, @Wave1DropDownValueChanged, true);
            app.Wave1DropDown.Value = 'none';
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-3.14 3.14];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [-10 10];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.DelayKnobLabel = uilabel(app.UIFigure);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.UIFigure, 'continuous');
            app.DelayKnob.ValueChangedFcn = createCallbackFcn(app, @DelayKnobValueChanged, true);
            app.PlayOriginalSignalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSignalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSignalSoundButtonPushed, true);
            app.PlayOriginalSignalSoundButton.Text = 'Play Original Signal/Sound';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop ';
            app.PlayModifiedSignalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayModifiedSignalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayModifiedSignalSoundButtonPushed, true);
            app.PlayModifiedSignalSoundButton.Text = 'Play Modified Signal/Sound';
            app.FiltersDropDownLabel = uilabel(app.UIFigure);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.UIFigure);
            app.FiltersDropDown.Items = {'--None--', 'Chipmunk Filter', 'Slow + Reverb Filter', 'Enhance', 'Bandpass', 'Bandstop'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = '--None--';
            app.GenerateTriangleWaveCheckBox = uicheckbox(app.UIFigure);
            app.GenerateTriangleWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateTriangleWaveCheckBoxValueChanged, true);
            app.GenerateTriangleWaveCheckBox.Text = 'Generate Triangle Wave';
            app.FilterFrequencyResponseLabel = uilabel(app.UIFigure);
            app.FilterFrequencyResponseLabel.Text = 'Filter Frequency Response:';
            app.FreqRangeSliderLabel = uilabel(app.UIFigure);
            app.FreqRangeSliderLabel.Text = 'Freq Range';
            app.FreqRangeSlider = uislider(app.UIFigure, 'range');
            app.FreqRangeSlider.Limits = [10 20000];
            app.FreqRangeSlider.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FreqRangeSlider.Value = [10 20000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end