classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FilterDropDown           matlab.ui.control.DropDown
        FilterDropDownLabel      matlab.ui.control.Label
        FrequencySlider          matlab.ui.control.Slider
        FrequencySliderLabel     matlab.ui.control.Label
        PhaseSlider              matlab.ui.control.Slider
        PhaseSliderLabel         matlab.ui.control.Label
        InstrumentDropDown       matlab.ui.control.DropDown
        InstrumentDropDownLabel  matlab.ui.control.Label
        Wave2DropDown            matlab.ui.control.DropDown
        Wave2DropDownLabel       matlab.ui.control.Label
        Wave1DropDown            matlab.ui.control.DropDown
        Wave1DropDownLabel       matlab.ui.control.Label
        SoundGeneratorsLabel     matlab.ui.control.Label
        PitchSlider              matlab.ui.control.Slider
        PitchSliderLabel         matlab.ui.control.Label
        VolumeSlider             matlab.ui.control.Slider
        VolumeSliderLabel        matlab.ui.control.Label
        SignalSelection          matlab.ui.control.DropDown
        SignalSoundFilesLabel    matlab.ui.control.Label
        graph_FD                 matlab.ui.control.UIAxes
        graph_TD                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SignalSelection
        function SignalSelectionValueChanged(app, event)
            value = app.SignalSelection.Value;
            if value == "Sin Wave"
                f = 10;                 % Signal frequency (Hz)
                phase_offset = 0;       % Initial phase offset in radians
                freq_offset = 0;        % Initial frequency offset in Hz
                Fs = 1000;              % Sampling frequency (Hz)

                [t, x, x_phase_offset, x_freq_offset] = gen_offset(f, phase_offset, freq_offset, Fs);
                
                % Graph in Time Domain (Phase Offset)
                h1 = plot(app.graph_TD, t, x_phase_offset)
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Time Domain (Phase Offset)');

                % Graph in Time Domain (Frequency Offset)
                plot(app.graph_FD, t, x_freq_offset)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Frequency Offset)');
                
            end
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            % WILL ONLY WORK WITH SIN WAVE INPUT
            % Will need to change for other signals
            f = 10
            Fs = 1000

            phase_offset = app.PhaseSlider.Value;
            [t, ~, x_phase_offset, ~] = gen_offset(f, phase_offset, 0, Fs);
            plot(app.graph_TD, t, x_phase_offset)
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            % WILL ONLY WORK WITH SIN WAVE INPUT
            % Will need to change for other signals
            f = 10
            Fs = 1000

            freq_offset = app.FrequencySlider.Value;
            [t, ~, ~, x_freq_offset] = gen_offset(f, 0, freq_offset, Fs);
            plot(app.graph_FD, t, x_freq_offset)
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph_TD = uiaxes(app.UIFigure);
            title(app.graph_TD, 'Time Domain')
            xlabel(app.graph_TD, 'X')
            ylabel(app.graph_TD, 'Y')
            zlabel(app.graph_TD, 'Z')
            app.graph_FD = uiaxes(app.UIFigure);
            title(app.graph_FD, 'Frequency Domain')
            xlabel(app.graph_FD, 'X')
            ylabel(app.graph_FD, 'Y')
            zlabel(app.graph_FD, 'Z')
            app.SignalSoundFilesLabel = uilabel(app.UIFigure);
            app.SignalSoundFilesLabel.Text = 'Signal/Sound Files';
            app.SignalSelection = uidropdown(app.UIFigure);
            app.SignalSelection.Items = {'Select', 'Sin Wave', 'Song 1', 'Option 3', 'Option 4'};
            app.SignalSelection.ValueChangedFcn = createCallbackFcn(app, @SignalSelectionValueChanged, true);
            app.SignalSelection.Value = 'Select';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.SoundGeneratorsLabel = uilabel(app.UIFigure);
            app.SoundGeneratorsLabel.Text = 'Sound Generators:';
            app.Wave1DropDownLabel = uilabel(app.UIFigure);
            app.Wave1DropDownLabel.Text = 'Wave 1';
            app.Wave1DropDown = uidropdown(app.UIFigure);
            app.Wave2DropDownLabel = uilabel(app.UIFigure);
            app.Wave2DropDownLabel.Text = 'Wave 2';
            app.Wave2DropDown = uidropdown(app.UIFigure);
            app.InstrumentDropDownLabel = uilabel(app.UIFigure);
            app.InstrumentDropDownLabel.Text = 'Instrument';
            app.InstrumentDropDown = uidropdown(app.UIFigure);
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-3.14 3.14];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [-10 10];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'--None--', 'Filter 1', 'Filter 2', 'Filter 3'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = '--None--';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end