classdef reverb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DownloadAudioButton  matlab.ui.control.Button
        ApplyReverbButton    matlab.ui.control.Button
        UploadAudioButton    matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audioIn % Input audio data
        audioOut % Output audio data with reverb applied
        fs % Sample rate of the audio file

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadAudioButton
        function UploadAudioButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3', 'Audio Files (*.wav, *.mp3)'});
            if isequal(file,0)
                disp('User selected Cancel');
            else
                [app.audioIn, app.fs] = audioread(fullfile(path, file));
                disp('Audio file uploaded successfully');
            end
        end

        % Button pushed function: ApplyReverbButton
        function ApplyReverbButtonPushed(app, event)
            if isempty(app.audioIn)
                disp('Please upload an audio file first.');
            return;
            end
    
    % Create a reverberator audio System object
            reverb = reverberator('PreDelay',0.5, 'WetDryMix',0.5);
    
    % Process the audio input with the reverberator
            app.audioOut = reverb(app.audioIn);
    
            disp('Reverb applied successfully');
           
        end

        % Button pushed function: DownloadAudioButton
        function DownloadAudioButtonPushed(app, event)
            [file, path] = uiputfile({'*.wav', 'WAV Files'; '*.mp3', 'MP3 Files'}, 'Save as');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                audiowrite(fullfile(path, file), app.audioOut, app.fs);
                disp('Audio file saved successfully');
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if isempty(app.audioIn) || isempty(app.audioOut)
                disp('Please upload and apply reverb to an audio file first.');
                return;
            end
    
    % Plot the original audio
            plot(app.UIAxes, app.audioIn, 'b');
            title(app.UIAxes, 'Original Audio');
            xlabel(app.UIAxes, 'Sample');
            ylabel(app.UIAxes, 'Amplitude');
            % Hold on to plot the reverbed audio on the same axes
    
    % Plot the reverbed audio


        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            plot(app.UIAxes2, app.audioOut, 'r');
            title(app.UIAxes2, 'Reverbed Audio');
            xlabel(app.UIAxes2, 'Sample');
            ylabel(app.UIAxes2, 'Amplitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UploadAudioButton = uibutton(app.UIFigure, 'push');
            app.UploadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioButtonPushed, true);
            app.UploadAudioButton.Text = 'Upload Audio';
            app.ApplyReverbButton = uibutton(app.UIFigure, 'push');
            app.ApplyReverbButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyReverbButtonPushed, true);
            app.ApplyReverbButton.Text = 'Apply Reverb';
            app.DownloadAudioButton = uibutton(app.UIFigure, 'push');
            app.DownloadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadAudioButtonPushed, true);
            app.DownloadAudioButton.Text = 'Download Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reverb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end