classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HeartWaveButton                matlab.ui.control.Button
        AdditiveSynthesisButton        matlab.ui.control.Button
        AmplitudeModulationButton      matlab.ui.control.Button
        FadeOutsEditField              matlab.ui.control.NumericEditField
        FadeOutsEditFieldLabel         matlab.ui.control.Label
        FadeInsEditField               matlab.ui.control.NumericEditField
        FadeInsEditFieldLabel          matlab.ui.control.Label
        GenerateMelodiesButton         matlab.ui.control.Button
        Input2DropDown                 matlab.ui.control.DropDown
        Input2DropDownLabel            matlab.ui.control.Label
        Input1DropDown                 matlab.ui.control.DropDown
        Input1DropDownLabel            matlab.ui.control.Label
        ConvolutionButton              matlab.ui.control.Button
        ReverbKnob                     matlab.ui.control.Knob
        ReverbKnobLabel                matlab.ui.control.Label
        SustainSlider                  matlab.ui.control.Slider
        SustainSliderLabel             matlab.ui.control.Label
        PlayAmpEnvelopeButton          matlab.ui.control.Button
        AmplitudeEnvelopeLabel         matlab.ui.control.Label
        ReleaseSlider                  matlab.ui.control.Slider
        ReleaseSliderLabel             matlab.ui.control.Label
        AttackSlider                   matlab.ui.control.Slider
        AttackSliderLabel              matlab.ui.control.Label
        DelaySlider                    matlab.ui.control.Slider
        DelaySliderLabel               matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
        SpeedSliderLabel               matlab.ui.control.Label
        ChorusKnob                     matlab.ui.control.Knob
        ChorusLabel                    matlab.ui.control.Label
        FreqRangeSlider                matlab.ui.control.RangeSlider
        FreqRangeSliderLabel           matlab.ui.control.Label
        FilterFrequencyResponseLabel   matlab.ui.control.Label
        GenerateTriangleWaveCheckBox   matlab.ui.control.CheckBox
        FiltersDropDown                matlab.ui.control.DropDown
        FiltersDropDownLabel           matlab.ui.control.Label
        PlayModifiedSignalSoundButton  matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PlayOriginalSignalSoundButton  matlab.ui.control.Button
        DelayKnob                      matlab.ui.control.Knob
        DelayKnobLabel                 matlab.ui.control.Label
        FrequencySlider                matlab.ui.control.Slider
        FrequencySliderLabel           matlab.ui.control.Label
        PhaseSlider                    matlab.ui.control.Slider
        PhaseSliderLabel               matlab.ui.control.Label
        WaveGeneratorDropDown          matlab.ui.control.DropDown
        WaveGeneratorDropDownLabel     matlab.ui.control.Label
        PitchSlider                    matlab.ui.control.Slider
        PitchSliderLabel               matlab.ui.control.Label
        VolumeSlider                   matlab.ui.control.Slider
        VolumeSliderLabel              matlab.ui.control.Label
        SignalSelection                matlab.ui.control.DropDown
        SignalSoundFilesLabel          matlab.ui.control.Label
        graph_FR_P                     matlab.ui.control.UIAxes
        graph_FR_M                     matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        graph_FD                       matlab.ui.control.UIAxes
        graph_TD                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        modifiedSignal = [];
        modifiedSignal_Fs = [];
        filtered_Signal = [];
        conv_input_1 = [];
        conv_input_2 = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SignalSelection
        function SignalSelectionValueChanged(app, event)
            value = app.SignalSelection.Value;
            if value == "Sin Wave"

                % Disable mod functionality for Sin Wave
                set(app.DelayKnob, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.PlayOriginalSignalSoundButton, 'Enable', 'off');
                set(app.PlayModifiedSignalSoundButton, 'Enable', 'off');
                set(app.VolumeSlider, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.StopButton, 'Enable', 'off');
                set(app.FiltersDropDown, 'Enable', 'off');
                set(app.ChorusKnob, 'Enable', 'off')
                set(app.FreqRangeSlider, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.ReverbKnob, 'Enable', 'off')
                set(app.FadeInsEditField, 'Enable', 'off')
                set(app.FadeOutsEditField, 'Enable', 'off')

                % Enable plot graph demonstration ui only
                set(app.PhaseSlider, 'Enable', 'on')
                set(app.FrequencySlider, 'Enable', 'on')
                set(app.WaveGeneratorDropDown, 'Enable', 'on')
                set(app.GenerateTriangleWaveCheckBox, 'Enable', 'on')
                set(app.PlayAmpEnvelopeButton, 'Enable', 'on')
                set(app.ReleaseSlider, 'Enable', 'on')
                set(app.AttackSlider, 'Enable', 'on')
                set(app.DelaySlider, 'Enable', 'on')
                set(app.SustainSlider, 'Enable', 'on')
                set(app.Input1DropDown, 'Enable', 'on')
                set(app.Input2DropDown, 'Enable', 'on')
                set(app.ConvolutionButton, 'Enable', 'on')
                set(app.GenerateMelodiesButton, 'Enable', 'on')
                

                f = 10;                 % Signal frequency (Hz)
                phase_offset = 0;       % Initial phase offset in radians
                freq_offset = 0;        % Initial frequency offset in Hz
                Fs = 1000;              % Sampling frequency (Hz)

                [t, x, x_phase_offset, x_freq_offset] = gen_offset(f, phase_offset, freq_offset, Fs);
                
                % Graph in Time Domain (Phase Offset)
                plot(app.graph_TD, t, x_phase_offset)
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Time Domain (Phase Offset)');

                % Graph in Time Domain (Frequency Offset)
                plot(app.graph_FD, t, x_freq_offset)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Frequency Offset)');
            end

            if value == "Sad Valentine by No Vacation"
                % Disable plot demo functionality
                set(app.PhaseSlider, 'Enable', 'off')
                set(app.FrequencySlider, 'Enable', 'off')
                set(app.WaveGeneratorDropDown, 'Enable', 'off')
                set(app.GenerateTriangleWaveCheckBox, 'Enable', 'off')
                set(app.PlayAmpEnvelopeButton, 'Enable', 'off')
                set(app.ReleaseSlider, 'Enable', 'off')
                set(app.AttackSlider, 'Enable', 'off')
                set(app.DelaySlider, 'Enable', 'off')
                set(app.SustainSlider, 'Enable', 'off')
                set(app.Input1DropDown, 'Enable', 'off')
                set(app.Input2DropDown, 'Enable', 'off')
                set(app.ConvolutionButton, 'Enable', 'off')

                % Enable mod functionality for No Vacation
                set(app.DelayKnob, 'Enable', 'on');
                set(app.PitchSlider, 'Enable', 'on');
                set(app.PlayOriginalSignalSoundButton, 'Enable', 'on');
                set(app.PlayModifiedSignalSoundButton, 'Enable', 'on');
                set(app.VolumeSlider, 'Enable', 'on');
                set(app.PitchSlider, 'Enable', 'on');
                set(app.StopButton, 'Enable', 'on');
                set(app.FiltersDropDown, 'Enable', 'on');
                set(app.ChorusKnob, 'Enable', 'on');
                set(app.SpeedSlider, 'Enable', 'on')
                set(app.ReverbKnob, 'Enable', 'on')
                set(app.FadeInsEditField, 'Enable', 'on')
                set(app.FadeOutsEditField, 'Enable', 'on')
                set(app.FreqRangeSlider, 'Enable', 'on')
                

                % Original signal and sampling frequency
                [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                app.modifiedSignal = your_sound;
                app.modifiedSignal_Fs = Fs_sound;
                
                % Time vector for original signal
                t_sound = linspace(0, length(your_sound) / Fs_sound, length(your_sound));
                
                % Plot the original signal in the time domain
                plot(app.graph_TD, t_sound, your_sound);
                title(app.graph_TD, 'Signal in Time Domain');
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                
                % Perform Fourier Transform to get frequency content
                N = length(your_sound);  % Length of the signal
                frequencies = (0:N-1) * Fs_sound / N;  % Frequency vector
                signal_fft = fft(your_sound);  % Compute FFT of the signal
                
                % Plot the magnitude of the signal in the frequency domain
                plot(app.graph_FD, frequencies, abs(signal_fft));
                title(app.graph_FD, 'Magnitude Spectrum of Signal');
                xlabel(app.graph_FD, 'Frequency (Hz)');
                ylabel(app.graph_FD, 'Magnitude');
            end

            if value == "White Noise"
                set(app.DelayKnob, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.PlayOriginalSignalSoundButton, 'Enable', 'off');
                set(app.PlayModifiedSignalSoundButton, 'Enable', 'off');
                set(app.VolumeSlider, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.StopButton, 'Enable', 'off');
                set(app.FiltersDropDown, 'Enable', 'off');
                set(app.ChorusKnob, 'Enable', 'off')
                set(app.FreqRangeSlider, 'Enable', 'off')
                set(app.PhaseSlider, 'Enable', 'off')
                set(app.FrequencySlider, 'Enable', 'off')
                set(app.WaveGeneratorDropDown, 'Enable', 'off')
                set(app.GenerateTriangleWaveCheckBox, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.PlayAmpEnvelopeButton, 'Enable', 'off')
                set(app.ReleaseSlider, 'Enable', 'off')
                set(app.AttackSlider, 'Enable', 'off')
                set(app.DelaySlider, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.SustainSlider, 'Enable', 'off')
                set(app.ReverbKnob, 'Enable', 'off')
                set(app.Input1DropDown, 'Enable', 'off')
                set(app.Input2DropDown, 'Enable', 'off')
                set(app.ConvolutionButton, 'Enable', 'off')
                set(app.FadeInsEditField, 'Enable', 'off')
                set(app.FadeOutsEditField, 'Enable', 'off')
                set(app.GenerateMelodiesButton, 'Enable', 'off')


                % Generate white noise signal
                frequency = 1000;
                duration = 1;
                signal = gen_white_noise(frequency, duration);
                
                % Generate time vector
                dt = 1 / frequency;
                t = 0:dt:duration-dt;
                
                % Plot in the time domain
                plot(app.graph_TD, t, signal);
                title(app.graph_TD, 'White Noise Signal (Time Domain)');
                xlabel(app.graph_TD, 'Time (s)');
                ylabel(app.graph_TD, 'Amplitude');
                
                % Perform FFT
                N = length(signal); % Length of the signal
                frequencies = (0:N-1) * (frequency / N); % Frequency vector
                fft_signal = fft(signal);
                
                % Plot in the frequency domain
                plot(app.graph_FD, frequencies, abs(fft_signal));
                title(app.graph_FD, 'White Noise Signal (Frequency Domain)');
                xlabel(app.graph_FD, 'Frequency (Hz)');
                ylabel(app.graph_FD, 'Amplitude');
                plot_freqRes(signal, signal, frequency, app.graph_FR_M, app.graph_FR_P);
            end


            if value == "Ode to Joy"
                set(app.DelayKnob, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.PlayOriginalSignalSoundButton, 'Enable', 'off');
                set(app.PlayModifiedSignalSoundButton, 'Enable', 'off');
                set(app.VolumeSlider, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.StopButton, 'Enable', 'off');
                set(app.FiltersDropDown, 'Enable', 'off');
                set(app.ChorusKnob, 'Enable', 'off')
                set(app.FreqRangeSlider, 'Enable', 'off')
                set(app.PhaseSlider, 'Enable', 'off')
                set(app.FrequencySlider, 'Enable', 'off')
                set(app.WaveGeneratorDropDown, 'Enable', 'off')
                set(app.GenerateTriangleWaveCheckBox, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.PlayAmpEnvelopeButton, 'Enable', 'off')
                set(app.ReleaseSlider, 'Enable', 'off')
                set(app.AttackSlider, 'Enable', 'off')
                set(app.DelaySlider, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.SustainSlider, 'Enable', 'off')
                set(app.ReverbKnob, 'Enable', 'off')
                set(app.Input1DropDown, 'Enable', 'off')
                set(app.Input2DropDown, 'Enable', 'off')
                set(app.ConvolutionButton, 'Enable', 'off')
                set(app.FadeInsEditField, 'Enable', 'off')
                set(app.FadeOutsEditField, 'Enable', 'off')
                
                [your_sound, Fs_sound] = audioread('Ode_to_Joy.wav');
                t_sound = linspace(0, length(your_sound) / Fs_sound, length(your_sound));

                % Plot the audio waveform
                plot(app.graph_TD,t_sound, your_sound);
                title(app.graph_TD,'Audio Waveform of Ode to Joy');
                xlabel(app.graph_TD,'Time (s)');
                ylabel(app.graph_TD,'Amplitude');
            end

            if value == "Mary Had a Little Lamb"
                set(app.DelayKnob, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.PlayOriginalSignalSoundButton, 'Enable', 'off');
                set(app.PlayModifiedSignalSoundButton, 'Enable', 'off');
                set(app.VolumeSlider, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');
                set(app.StopButton, 'Enable', 'off');
                set(app.FiltersDropDown, 'Enable', 'off');
                set(app.ChorusKnob, 'Enable', 'off')
                set(app.FreqRangeSlider, 'Enable', 'off')
                set(app.PhaseSlider, 'Enable', 'off')
                set(app.FrequencySlider, 'Enable', 'off')
                set(app.WaveGeneratorDropDown, 'Enable', 'off')
                set(app.GenerateTriangleWaveCheckBox, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.PlayAmpEnvelopeButton, 'Enable', 'off')
                set(app.ReleaseSlider, 'Enable', 'off')
                set(app.AttackSlider, 'Enable', 'off')
                set(app.DelaySlider, 'Enable', 'off')
                set(app.SpeedSlider, 'Enable', 'off')
                set(app.SustainSlider, 'Enable', 'off')
                set(app.ReverbKnob, 'Enable', 'off')
                set(app.Input1DropDown, 'Enable', 'off')
                set(app.Input2DropDown, 'Enable', 'off')
                set(app.ConvolutionButton, 'Enable', 'off')
                set(app.FadeInsEditField, 'Enable', 'off')
                set(app.FadeOutsEditField, 'Enable', 'off')

                [your_sound, Fs_sound] = audioread('Mary_Had_A_Little_Lamb.wav');
                t_sound = linspace(0, length(your_sound) / Fs_sound, length(your_sound));
                
                % Plot the audio waveform
                plot(app.graph_TD,t_sound, your_sound);
                title(app.graph_TD,'Audio Waveform of Mary Had a Little Lamb');
                xlabel(app.graph_TD,'Time (s)');
                ylabel(app.graph_TD,'Amplitude');
            end


        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            if app.SignalSelection.Value == "Sin Wave"
                f = 10
                Fs = 1000
    
                phase_offset = app.PhaseSlider.Value;
                [t, ~, x_phase_offset, ~] = gen_offset(f, phase_offset, 0, Fs);
                plot(app.graph_TD, t, x_phase_offset)
            end
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            if app.SignalSelection.Value == "Sin Wave"
                f = 10
                Fs = 1000
    
                freq_offset = app.FrequencySlider.Value;
                [t, ~, ~, x_freq_offset] = gen_offset(f, 0, freq_offset, Fs);
                plot(app.graph_FD, t, x_freq_offset)
            end
        end

        % Button pushed function: PlayOriginalSignalSoundButton
        function PlayOriginalSignalSoundButtonPushed(app, event)
            clear sound; % Stop sound from playing
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                [your_sound, Fs_sound] = audioread('sadvalentine.wav'); % Update the file name as needed
                sound(your_sound * app.VolumeSlider.Value, Fs_sound)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound; % Stop sound from playing
        end

        % Button pushed function: PlayModifiedSignalSoundButton
        function PlayModifiedSignalSoundButtonPushed(app, event)
            clear sound; % Stop sound from playing

            if app.SignalSelection.Value == "Sad Valentine by No Vacation"

                if app.FiltersDropDown.Value == "Chipmunk Filter"
                    originalSignal = audioread('sadvalentine.wav'); % Provide your audio file
                    factor = 2.0;
                    filteredSignal = gen_highPitch(originalSignal, factor);
                    sound(filteredSignal, 44100)
                    return
                end

                if app.FiltersDropDown.Value == "Slow + Reverb Filter"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    factor = 0.25; % Slow down by 50%
                    reverberationTime = 1.0; % One second reverberation time
                    filteredSignal = gen_slowReverb(your_sound, factor, reverberationTime);
                    sound(filteredSignal, 44100)
                    return
                end

                if app.FiltersDropDown.Value == "Bandpass"
                    sound(app.filtered_Signal, app.modifiedSignal_Fs)
                    return
                end
                
                if app.FiltersDropDown.Value == "Bandstop"
                    sound(app.filtered_Signal, app.modifiedSignal_Fs)
                    return
                end

                if app.FiltersDropDown.Value == "Enhance"
                    sound(real(app.filtered_Signal), app.modifiedSignal_Fs)
                    return
                end

                if app.FiltersDropDown.Value == "Flanger"
                    time_delay = 0.005;  % 5 milliseconds
                    osc_freq = 0.5;      % Frequency of the oscillation (in Hz)
                    amp = 0.5;           % Amplitude of the delay signal (should be less than 1 to avoid distortion)
                    output_signal = flanger(app.modifiedSignal, time_delay, osc_freq, amp, app.modifiedSignal_Fs);
                    sound(output_signal, app.modifiedSignal_Fs)
                    return
                end

                if app.FiltersDropDown.Value == "Echo"
                    delay_seconds = 0.2;  % 200 milliseconds
                    decay_factor = 0.5;    % Adjust as needed (should be less than 1)
                    output_signal = apply_echo(app.modifiedSignal, app.modifiedSignal_Fs, delay_seconds, decay_factor);
                    sound(output_signal, app.modifiedSignal_Fs)
                    return
                end

                if app.FilterFrequencyResponseLabel == "Muffled"
                    output_signal = gen_muffled(app.modifiedSignal, app.modifiedSignal_Fs);
                    sound(output_signal, app.modifiedSignal_Fs)
                    return
                end

                if app.FilterFrequencyResponseLabel == "Distortion"
                    LOW_FREQ = 1000;
                    HIGH_FREQ = 5000;
                    output_signal = dist_filt(app.modifiedSignal, app.modifiedSignal_Fs, LOW_FREQ, HIGH_FREQ);
                    sound(output_signal, app.modifiedSignal_Fs)
                    return
                end
                

                % SIGNAL MODIFICATIONS
                delay_value = app.DelayKnob.Value;
                pitch_value = app.PitchSlider.Value;
                chorus_value = app.ChorusKnob.Value;
                speed_value = app.SpeedSlider.Value;
                reverb_value = app.ReverbKnob.Value;
                fade_in_value = app.FadeInsEditField.Value; 
                fade_out_value = app.FadeOutsEditField.Value; 

                copy = app.modifiedSignal;
                copy_fs = app.modifiedSignal_Fs;
            
                %Fade in
                if fade_in_value ~=0
                    copy = audio_fade_in(copy, copy_fs, fade_in_value); 
                end 

                %Fade out
                if fade_out_value ~=0
                    copy = audio_fade_out(copy, copy_fs, fade_out_value); 
                end 
                % Pitch
                if pitch_value ~= 0
                    copy = shiftPitch(copy, pitch_value); 
                end

                % Reverb
                if reverb_value ~= 0
                    initial_reverb_gain = 0.5;
                    room_size = 0.75;  % Hypothetical parameter, adjust based on your reverb implementation
                    copy = gen_reverb(copy, copy_fs, reverb_value, initial_reverb_gain, room_size);
                end

                % Chorus
                if chorus_value ~= 0
                    copy = chorus(copy, 3.0, 0.03, 0.5, chorus_value, copy_fs);
                end

                % Delay
                if delay_value ~= 0
                    initial_feedback_gain = 0.5;
                    copy = gen_delay(copy, copy_fs, delay_value, initial_feedback_gain);
                end

                % Speed
                if speed_value ~= 0
                    freqChange = 2^(speed_value/12);
                    rounded_fs = round(copy_fs*freqChange);
                    copy = resample(copy, copy_fs, rounded_fs);
                end 

                % PLAYING MODIFIED SOUND WITH ALL MODS :)
                sound(copy * app.VolumeSlider.Value, copy_fs)
            end

        end

        % Value changed function: DelayKnob
        function DelayKnobValueChanged(app, event)
            delay_time = app.DelayKnob.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copied_signal = app.modifiedSignal;
                copied_signal_fs = app.modifiedSignal_Fs;
                initial_feedback_gain = 0.5;
                copied_signal = gen_delay(copied_signal, copied_signal_fs, delay_time, initial_feedback_gain);
                t_sound = linspace(0, length(copied_signal) / copied_signal_fs, length(copied_signal));
                plot(app.graph_FD, t_sound, copied_signal)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Delayed Signal)');
                plot_freqRes(app.modifiedSignal, copied_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end
        end

        % Value changed function: FiltersDropDown
        function FiltersDropDownValueChanged(app, event)
            value = app.FiltersDropDown.Value;
            if value == "Chipmunk Filter"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    filteredSignal = gen_highPitch(your_sound, Fs_sound);
                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Chipmunk Filter');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');   
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end

            if value == "Slow + Reverb Filter"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    factor = 0.25; % Slow down by 50%
                    reverberationTime = 1.0; % One second reverberation time
                    [your_sound, Fs_sound] = audioread('sadvalentine.wav');
                    filteredSignal = gen_slowReverb(your_sound, factor, reverberationTime);
                    t_sound = linspace(0, length(filteredSignal) / Fs_sound, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain (Slow + Reverb Filter)');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(your_sound, filteredSignal, Fs_sound, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "Select"
                cla(app.graph_FD);
            end

            if value == "Bandpass"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandpass1(freq1,freq2,'sadvalentine.wav');
                    app.filtered_Signal = filteredSignal;

                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / app.modifiedSignal_Fs, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Bandpass');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');

                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(app.modifiedSignal, filteredSignal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "Bandstop"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    selectedRange =app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    filteredSignal = bandstop1(freq1,freq2,'sadvalentine.wav');
                    app.filtered_Signal = filteredSignal;

                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / app.modifiedSignal_Fs, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, filteredSignal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Bandstop');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');

                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(app.modifiedSignal, filteredSignal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end
            if value == "Enhance"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    selectedRange = app.FreqRangeSlider.Value;
                    freq1= selectedRange(1);
                    freq2= selectedRange(2);
                    z=app.VolumeSlider.Value;
                    filteredSignal = enhance1(freq1,freq2,z,'sadvalentine.wav');
                    app.filtered_Signal = filteredSignal;

                    % Time vector for filtered signal
                    t_sound = linspace(0, length(filteredSignal) / app.modifiedSignal_Fs, length(filteredSignal));
                    % Plot the filtered signal in the time domain
                    plot(app.graph_FD, t_sound, real(filteredSignal), 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Enhance');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    % Plot the Filter Frequency Response graphs
                    plot_freqRes(app.modifiedSignal, filteredSignal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end

            if value == "Flanger"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    time_delay = 0.005;  % 5 milliseconds
                    osc_freq = 0.5;      % Frequency of the oscillation (in Hz)
                    amp = 0.5;           % Amplitude of the delay signal (should be less than 1 to avoid distortion)
                    output_signal = flanger(app.modifiedSignal, time_delay, osc_freq, amp, app.modifiedSignal_Fs);
                    t = (0:length(app.modifiedSignal)-1) / app.modifiedSignal_Fs;
                    plot(app.graph_FD, t, output_signal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Flanger');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    plot_freqRes(app.modifiedSignal, output_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end

            if value == "Echo"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    delay_seconds = 0.2;  % 200 milliseconds
                    decay_factor = 0.5;    % Adjust as needed (should be less than 1)
                    output_signal = apply_echo(app.modifiedSignal, app.modifiedSignal_Fs, delay_seconds, decay_factor);
                    
                    % Plot the signal in the time domain
                    t = (0:length(output_signal)-1) / app.modifiedSignal_Fs;
                    plot(app.graph_FD, t, output_signal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Echo');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    
                    % Plot the frequency response
                    plot_freqRes(app.modifiedSignal, output_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end

            if value == "Muffled"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                    output_signal = gen_muffled(app.modifiedSignal, app.modifiedSignal_Fs);
                    
                    % Plot the signal in the time domain
                    t = (0:length(output_signal)-1) / app.modifiedSignal_Fs;
                    plot(app.graph_FD, t, output_signal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Muffled');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    
                    % Plot the frequency response
                    plot_freqRes(app.modifiedSignal, output_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end 

            if value == "Distortion"
                if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                   LOW_FREQ = 1000;
                   HIGH_FREQ = 5000;
                   output_signal = dist_filt(app.modifiedSignal, app.modifiedSignal_Fs, LOW_FREQ, HIGH_FREQ);
                    
                    % Plot the signal in the time domain
                    t = (0:length(output_signal)-1) / app.modifiedSignal_Fs;
                    plot(app.graph_FD, t, output_signal, 'r');
                    title(app.graph_FD, 'Signal in Time Domain - Distorted');
                    xlabel(app.graph_FD, 'Time (s)');
                    ylabel(app.graph_FD, 'Amplitude');
                    
                    % Plot the frequency response
                    plot_freqRes(app.modifiedSignal, output_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
                end
            end 
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            clear sound; % Stop sound from playing

            value = app.PitchSlider.Value;
           
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy = app.modifiedSignal;
                
                output_wave = shiftPitch(copy, value); 
                % Display the changed pitch
                t_sound = linspace(0, length(output_wave) / app.modifiedSignal_Fs, length(output_wave));
                plot(app.graph_FD, t_sound, output_wave);
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Signal)');
                plot_freqRes(app.modifiedSignal, output_wave, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                % Call the ValueChanged function of GenerateTriangleWaveCheckBox
                GenerateTriangleWaveCheckBoxValueChanged(app);
        end

        % Value changed function: GenerateTriangleWaveCheckBox
        function GenerateTriangleWaveCheckBoxValueChanged(app, event)
            % Check the state of the checkbox
            isChecked = app.GenerateTriangleWaveCheckBox.Value;
            
            if isChecked
                % If the checkbox is checked, generate and plot the triangle waveform
                % Generate the triangle waveform
                Fs = 44100;  % Sampling frequency
                dur = 1;     % Duration in seconds
                % Get frequency and phase from sliders
                frequency = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;

                % Generate the triangle waveform using a piecewise function
                triangleWaveform = gen_triangleWaveform(1, frequency, phase, Fs, dur, 0);

                % Generate time vector based on the waveform length
                t = linspace(0, dur, length(triangleWaveform));
                
                % Clear the custom axes
                cla(app.UIAxes);

                % Plot the triangle waveform on the custom axes
                plot(app.UIAxes, t, triangleWaveform);
                xlabel(app.UIAxes, 'Time');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Triangle Waveform');
            else
                % If the checkbox is unchecked, clear the custom axes
                cla(app.UIAxes);
            end
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy_signal = value * app.modifiedSignal;

                plot(app.graph_TD,copy_signal);
    
                % Perform Fourier Transform to get frequency content
                N = length(copy_signal);  % Length of the signal
                frequencies = (0:N-1) * app.modifiedSignal_Fs / N;  % Frequency vector
                signal_fft = fft(copy_signal);  % Compute FFT of the signal
                    
                % Plot the magnitude of the signal in the frequency domain
                plot(app.graph_FD, frequencies, abs(signal_fft));
                title(app.graph_FD, 'Magnitude Spectrum of Signal');
                xlabel(app.graph_FD, 'Frequency (Hz)');
                ylabel(app.graph_FD, 'Magnitude');
                plot_freqRes(app.modifiedSignal, copy_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end
        end

        % Value changed function: WaveGeneratorDropDown
        function WaveGeneratorDropDownValueChanged(app, event)
            value = app.WaveGeneratorDropDown.Value;
            
            %Updated for Sine, Cosine, Sawtooth, and Square by Collin
            %Walmsley 3/13/2024
            
            if (value == "Sine")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sine

                %Generate Sine
                sineWaveform = gen_sine(amp, freq, phase, fs, dur, duty);
                
                % time plot for graph
                t = linspace(0, dur, length(sineWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                sineFD = fft(sineWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, sineWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Sine Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(sineFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Sine Waveform Frequency');

            elseif (value == "Cosine")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sine

                %Generate Cosine
                cosineWaveform = gen_cosine(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(cosineWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                cosineFD = fft(cosineWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, cosineWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Cosine Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(cosineFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Cosine Waveform Frequency');


            elseif (value == "Sawtooth")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 0;   %not applicable for Sawtooth

                %Generate Sawtooth
                sawtoothWaveform = gen_sawtooth(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(sawtoothWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                sawFD = fft(sawtoothWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, sawtoothWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Sawtooth Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(sawFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Sawtooth Waveform Frequency');

            elseif (value == "Square")
                amp = 1;
                freq = app.FrequencySlider.Value;
                phase = app.PhaseSlider.Value;
                fs = 44100;
                dur = 1;    %duration in seconds
                duty = 50;   %0-100 for percent of wave in a period is HIGH

                %Generate Square
                squareWaveform = gen_square(amp, freq, phase, fs, dur, duty);

                % time plot for graph
                t = linspace(0, dur, length(squareWaveform));

                % variables for frequency graph
                df = 1/dur;
                f = (0:dur*fs-1)*df;
                squareFD = fft(squareWaveform);

                cla(app.graph_TD);
                cla(app.graph_FD);

                %plot time domain
                plot(app.graph_TD, t, squareWaveform);
                xlabel(app.graph_TD, 'Time');
                ylabel(app.graph_TD, 'Amplitude');
                title(app.graph_TD, 'Square Waveform');

                %plot frequency domain
                plot(app.graph_FD, f, abs(squareFD));
                xlabel(app.graph_FD, 'Frequency');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Square Waveform Frequency');
            else
                cla(app.graph_TD);
                cla(app.graph_FD);
            end
        end

        % Value changed function: FreqRangeSlider
        function FreqRangeSliderValueChanged(app, event)
            value = app.FreqRangeSlider.Value;
        end

        % Value changed function: ChorusKnob
        function ChorusKnobValueChanged(app, event)
            value = app.ChorusKnob.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copied_signal = app.modifiedSignal;
                copied_signal_fs = app.modifiedSignal_Fs;
        
                % Apply chorus effect to the loaded audio signal
                copied_signal = chorus(copied_signal, 3.0, 0.03, 0.5, value, copied_signal_fs);
        
                % Plot the chorus-affected signal
                t_sound = linspace(0, length(copied_signal) / app.modifiedSignal_Fs, length(copied_signal));
                plot(app.graph_FD, t_sound, copied_signal)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Chorus Affected Signal)');
                plot_freqRes(app.modifiedSignal, copied_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end  
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            clear sound; % Stop sound from playing
            value = app.SpeedSlider.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
              audio = app.modifiedSignal;
              Fs = app.modifiedSignal_Fs;
              freqChange = 2^(value/12);
              new_freq = round(Fs * freqChange);
              output_wave = resample(audio, Fs, new_freq);

              t_sound = linspace(0, length(output_wave) / Fs, length(output_wave));
              plot(app.graph_FD, t_sound, output_wave);
              xlabel(app.graph_FD, 'Time (s)');
              ylabel(app.graph_FD, 'Amplitude');
              title(app.graph_FD, 'Time Domain (Signal after Speed Change)');
              plot_freqRes(audio, output_wave, Fs, app.graph_FR_M, app.graph_FR_P);
              plot_freqRes(app.modifiedSignal, output_wave, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end


        end

        % Button pushed function: PlayAmpEnvelopeButton
        function PlayAmpEnvelopeButtonPushed(app, event)
            if app.SignalSelection.Value == "Sin Wave"
                delayValue = app.DelaySlider.Value;
                attackValue = app.AttackSlider.Value;
                releaseValue = app.ReleaseSlider.Value;
                sustainValue = app.SustainSlider.Value;

                duration = 3;           % Duration of the sine wave in seconds
                sampling_rate = 44100;  % Sampling rate (samples per second)
                frequency = 440;        % Frequency of the sine wave in Hz (A4 note)
                t = 0:1/sampling_rate:duration;

                % Generate sine wave
                sine_wave = sin(2 * pi * frequency * t);
                output_signal = AmplitudeEnvelope(sine_wave, sampling_rate, attackValue, delayValue, sustainValue, releaseValue);
    
                % Plot the output signal
                plot_freqRes(sine_wave, output_signal, sampling_rate, app.graph_FR_M, app.graph_FR_P);
                t_output = (0:length(output_signal)-1) / sampling_rate;
                plot(app.graph_FD, t_output, output_signal);
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Output Signal with Amplitude Envelope');
                % Set the y-axis limits based on the signal amplitude
                ylim(app.graph_FD, [-1.2*max(abs(output_signal)), 1.2*max(abs(output_signal))]);

                sound(output_signal, sampling_rate);

            end
        end

        % Value changed function: ReverbKnob
        function ReverbKnobValueChanged(app, event)
            reverb_time = app.ReverbKnob.Value;
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copied_signal = app.modifiedSignal;
                copied_signal_fs = app.modifiedSignal_Fs;
                initial_reverb_gain = 0.5;
                room_size = 0.75;  % Hypothetical parameter, adjust based on your reverb implementation
                copied_signal = gen_reverb(copied_signal, copied_signal_fs, reverb_time, initial_reverb_gain, room_size);
                t_sound = linspace(0, length(copied_signal) / copied_signal_fs, length(copied_signal));
                plot(app.graph_FD, t_sound, copied_signal)
                xlabel(app.graph_FD, 'Time (s)');
                ylabel(app.graph_FD, 'Amplitude');
                title(app.graph_FD, 'Time Domain (Reverberated Signal)');
                plot_freqRes(app.modifiedSignal, copied_signal, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            fs = 2000;
            t = 0:1/fs:1-1/fs;
            f = 10;
            output_signal = convolution(app.conv_input_1, app.conv_input_2);
            t_new = 0:1/fs:(length(output_signal)-1)/fs;

            plot(app.graph_FD, t_new, output_signal);
            xlabel(app.graph_FD, 'Time (s)');
            ylabel(app.graph_FD, 'Amplitude');
            title(app.graph_FD, 'Time Domain (Convolved Signal)');
            
        end

        % Value changed function: Input1DropDown
        function Input1DropDownValueChanged(app, event)
            value = app.Input1DropDown.Value;
            fs = 2000;
            t = 0:1/fs:1-1/fs;
            f = 10;

            if value == "Select"
                app.conv_input_1 = 0;
            end

            if value == "Rect"
                
                app.conv_input_1 = square(2*pi*f*t);

            end
            if value == "Sin"
                app.conv_input_1 = sin(2*pi*f*t);

            end
            if value == "Triangle"
                app.conv_input_1 = sawtooth(2*pi*f*t, 0.5);
            end
        end

        % Value changed function: Input2DropDown
        function Input2DropDownValueChanged(app, event)
            value = app.Input2DropDown.Value;
            fs = 2000;
            t = 0:1/fs:1-1/fs;
            f = 10;
            
            if value == "Select"
                app.conv_input_2 = 0;
            end
            
            if value == "Rect"
                app.conv_input_2 = square(2*pi*f*t);
            end
            
            if value == "Sin"
                app.conv_input_2 = sin(2*pi*f*t);
            end
            
            if value == "Triangle"
                app.conv_input_2 = sawtooth(2*pi*f*t, 0.5);
            end
        end

        % Button pushed function: GenerateMelodiesButton
        function GenerateMelodiesButtonPushed(app, event)
            clear sound; % Stop sound from playing
            clear sound; % Stop sound from playing
            amp = 0.5; % You can adjust the amplitude as desired
            freq = 0; % Not used in this function
            phase = pi/4; % Introduce a phase shift of pi/4 as an example
            fs = 44100; % Sampling frequency, recommended 44.1k
            dur = 0.3; % Duration of a single note, recommended 0.3
            duty = 0; % Not used in this function
            
            gen_song_examples(amp, freq, phase, fs, dur, duty);

        end

        % Value changed function: FadeInsEditField
        function FadeInsEditFieldValueChanged(app, event)
            clear sound 

            value = app.FadeInsEditField.Value;
            
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy = app.modifiedSignal; 
                freq = app.modifiedSignal_Fs; 

                output_wave = audio_fade_in(copy, freq, value); 
                t_sound = linspace(0, length(output_wave) / freq, length(output_wave)); 
                plot(app.graph_FD, t_sound, output_wave); 
                xlabel(app.graph_FD, 'Time (s)'); 
                ylabel(app.graph_FD, 'Amplitude'); 
                title(app.graph_FD, 'Time Domain (Fade In - Signal)'); 
                plot_freqRes(app.modifiedSignal, output_wave, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end 
        end

        % Value changed function: FadeOutsEditField
        function FadeOutsEditFieldValueChanged(app, event)
            clear sound 

            value = app.FadeOutsEditField.Value;
            
            if app.SignalSelection.Value == "Sad Valentine by No Vacation"
                copy = app.modifiedSignal; 
                freq = app.modifiedSignal_Fs; 

                output_wave = audio_fade_out(copy, freq, value); 
                t_sound = linspace(0, length(output_wave) / freq, length(output_wave)); 
                plot(app.graph_FD, t_sound, output_wave); 
                xlabel(app.graph_FD, 'Time (s)'); 
                ylabel(app.graph_FD, 'Amplitude'); 
                title(app.graph_FD, 'Time Domain (Fade Out - Signal)'); 
                plot_freqRes(app.modifiedSignal, output_wave, app.modifiedSignal_Fs, app.graph_FR_M, app.graph_FR_P);
            end 
        end

        % Button pushed function: AmplitudeModulationButton
        function AmplitudeModulationButtonPushed(app, event)
            carrier_f = 1000;    % Carrier frequency in Hz
            mod_f = 50;          % Modulation frequency in Hz
            mod_index = 0.5;     % Modulation index
            fs = 44100;          % Sampling frequency in Hz
            dur = 1;             % Duration in seconds
            
            % Generate amplitude-modulated signal
            a = gen_ampMod(carrier_f, mod_f, mod_index, fs, dur);
            
            % Plot the amplitude-modulated signal using graph_FD
            t = 0:1/fs:dur-1/fs;
            t_sound = linspace(0, dur, length(a)); % Adjusted time vector
            plot(app.graph_FD, t_sound, a);
            xlabel(app.graph_FD, 'Time (s)');
            ylabel(app.graph_FD, 'Amplitude');
            title(app.graph_FD, 'Amplitude-Modulated Signal');
            
            % Set the axis limits
            axis(app.graph_FD, [0 0.25 -1.5 1.5]);
        end

        % Button pushed function: AdditiveSynthesisButton
        function AdditiveSynthesisButtonPushed(app, event)
        % Define parameters
        clear sound
        frequencies = [220 330 440]; % Frequencies of sine waves (Hz)
        amplitudes = [1 0.5 0.3]; % Amplitudes corresponding to frequencies
        phases = [0 pi/2 pi]; % Phase shifts corresponding to frequencies
        duration = 3; % Duration of the combined sound waves (seconds)
        samplingRate = 44100; % Sampling rate (Hz)
        playbackDirection = 'forward'; % Play audio forward
        randomnessLevel = 0.5; % Intensity of randomness
        filterCutoffFrequency = 2000; % Cutoff frequency of filter (Hz)
        filterResonance = 1; % Resonance of filter
        
        % Generate sound using additive synthesis
        outputSignal = additiveSynthesis3(frequencies, amplitudes, phases, duration, samplingRate, playbackDirection, randomnessLevel, filterCutoffFrequency, filterResonance);
        
        % Get the time vector
        t = 0:1/samplingRate:duration;
        
        % Plot the output signal with phase offset
        plot(app.graph_TD, t, outputSignal) % Assuming app.graph_TD is your handle to the time domain axis
        xlabel(app.graph_TD, 'Time (s)');
        ylabel(app.graph_TD, 'Amplitude');
        title(app.graph_TD, 'Time Domain');
        
        % Plot the output signal with frequency offset
        plot(app.graph_FD, t, outputSignal) % Assuming app.graph_FD is your handle to the frequency domain axis
        xlabel(app.graph_FD, 'Time (s)');
        ylabel(app.graph_FD, 'Amplitude');
        title(app.graph_FD, 'Time Domain');
        sound(outputSignal, samplingRate);
        end

        % Button pushed function: HeartWaveButton
        function HeartWaveButtonPushed(app, event)
            clear sound
            fs = 44100;  % Sampling frequency for the audio
dur = 10;    % Duration of the audio (in seconds)
t = linspace(0, dur, dur*fs);
            % Generate ECG-like waveform
            ecg_waveform = gen_ecg_waveform(t);
            
            % Normalize ECG waveform to range [-1, 1]
            ecg_waveform = ecg_waveform / max(abs(ecg_waveform));
            
            % Generate heartbeat sound
            heartbeat_sound = gen_heartbeat_sound(t, dur);
            
            % Combine ECG waveform and heartbeat sound
            combined_signal = ecg_waveform + 0.3 * heartbeat_sound; % Adjusting the amplitude of the heartbeat sound


            t = linspace(0, dur, dur*fs);
            
            % Plot combined ECG waveform in the Time Domain
            plot(app.graph_TD, t, combined_signal);
            xlabel(app.graph_TD, 'Time (s)');
            ylabel(app.graph_TD, 'Amplitude');
            title(app.graph_TD, 'ECG with Heartbeat Sound (Time Domain)');
            grid(app.graph_TD, 'on');
            
            % Compute the frequency domain representation
            fft_combined_signal = fft(combined_signal);
            frequencies = linspace(0, fs, length(fft_combined_signal));
            
            % Plot frequency domain representation in the Frequency Domain
            plot(app.graph_FD, frequencies(1:end/2), abs(fft_combined_signal(1:end/2))); % Plot only positive frequencies
            xlabel(app.graph_FD, 'Frequency (Hz)');
            ylabel(app.graph_FD, 'Magnitude');
            title(app.graph_FD, 'ECG with Heartbeat Sound (Frequency Domain)');
            grid(app.graph_FD, 'on');
    
                
            % Play the sound
            sound(combined_signal, fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graph_TD = uiaxes(app.UIFigure);
            title(app.graph_TD, 'Time Domain')
            xlabel(app.graph_TD, 'X')
            ylabel(app.graph_TD, 'Y')
            zlabel(app.graph_TD, 'Z')
            app.graph_FD = uiaxes(app.UIFigure);
            title(app.graph_FD, 'Frequency Domain')
            xlabel(app.graph_FD, 'X')
            ylabel(app.graph_FD, 'Y')
            zlabel(app.graph_FD, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Generate Triangle Wave')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.graph_FR_M = uiaxes(app.UIFigure);
            title(app.graph_FR_M, 'Magnitude')
            xlabel(app.graph_FR_M, 'Frequency')
            ylabel(app.graph_FR_M, 'Magnitude')
            zlabel(app.graph_FR_M, 'Z')
            app.graph_FR_P = uiaxes(app.UIFigure);
            title(app.graph_FR_P, 'Phase')
            xlabel(app.graph_FR_P, 'Frequency')
            ylabel(app.graph_FR_P, 'Phase')
            zlabel(app.graph_FR_P, 'Z')
            app.SignalSoundFilesLabel = uilabel(app.UIFigure);
            app.SignalSoundFilesLabel.Text = 'Signal/Sound Files';
            app.SignalSelection = uidropdown(app.UIFigure);
            app.SignalSelection.Items = {'Select', 'Sin Wave', 'Sad Valentine by No Vacation', 'White Noise', 'Mary Had a Little Lamb', 'Ode to Joy'};
            app.SignalSelection.ValueChangedFcn = createCallbackFcn(app, @SignalSelectionValueChanged, true);
            app.SignalSelection.Value = 'Select';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 0.5;
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-5 5];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.PitchSlider.MinorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.WaveGeneratorDropDownLabel = uilabel(app.UIFigure);
            app.WaveGeneratorDropDownLabel.Text = 'Wave  Generator';
            app.WaveGeneratorDropDown = uidropdown(app.UIFigure);
            app.WaveGeneratorDropDown.Items = {'none', 'Sine', 'Cosine', 'Sawtooth', 'Square'};
            app.WaveGeneratorDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveGeneratorDropDownValueChanged, true);
            app.WaveGeneratorDropDown.Value = 'none';
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-3.14 3.14];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [-10 10];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.DelayKnobLabel = uilabel(app.UIFigure);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.UIFigure, 'continuous');
            app.DelayKnob.ValueChangedFcn = createCallbackFcn(app, @DelayKnobValueChanged, true);
            app.PlayOriginalSignalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSignalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalSignalSoundButtonPushed, true);
            app.PlayOriginalSignalSoundButton.Text = 'Play Original Signal/Sound';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop ';
            app.PlayModifiedSignalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayModifiedSignalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayModifiedSignalSoundButtonPushed, true);
            app.PlayModifiedSignalSoundButton.Text = 'Play Modified Signal/Sound';
            app.FiltersDropDownLabel = uilabel(app.UIFigure);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.UIFigure);
            app.FiltersDropDown.Items = {'--None--', 'Chipmunk Filter', 'Slow + Reverb Filter', 'Bandpass', 'Bandstop', 'Enhance', 'Flanger', 'Echo', 'Muffled', 'Distortion'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = '--None--';
            app.GenerateTriangleWaveCheckBox = uicheckbox(app.UIFigure);
            app.GenerateTriangleWaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateTriangleWaveCheckBoxValueChanged, true);
            app.GenerateTriangleWaveCheckBox.Text = 'Generate Triangle Wave';
            app.FilterFrequencyResponseLabel = uilabel(app.UIFigure);
            app.FilterFrequencyResponseLabel.Text = 'Filter Frequency Response:';
            app.FreqRangeSliderLabel = uilabel(app.UIFigure);
            app.FreqRangeSliderLabel.Text = 'FreqRangeSlider';
            app.FreqRangeSlider = uislider(app.UIFigure, 'range');
            app.FreqRangeSlider.Limits = [10 20000];
            app.FreqRangeSlider.ValueChangedFcn = createCallbackFcn(app, @FreqRangeSliderValueChanged, true);
            app.FreqRangeSlider.Value = [10 5000];
            app.ChorusLabel = uilabel(app.UIFigure);
            app.ChorusLabel.Text = 'Chorus';
            app.ChorusKnob = uiknob(app.UIFigure, 'continuous');
            app.ChorusKnob.ValueChangedFcn = createCallbackFcn(app, @ChorusKnobValueChanged, true);
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [-12 12];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.UIFigure);
            app.DelaySlider.Limits = [0 1];
            app.AttackSliderLabel = uilabel(app.UIFigure);
            app.AttackSliderLabel.Text = 'Attack';
            app.AttackSlider = uislider(app.UIFigure);
            app.AttackSlider.Limits = [0 1];
            app.ReleaseSliderLabel = uilabel(app.UIFigure);
            app.ReleaseSliderLabel.Text = 'Release';
            app.ReleaseSlider = uislider(app.UIFigure);
            app.ReleaseSlider.Limits = [0 1];
            app.AmplitudeEnvelopeLabel = uilabel(app.UIFigure);
            app.AmplitudeEnvelopeLabel.Text = 'Amplitude Envelope';
            app.PlayAmpEnvelopeButton = uibutton(app.UIFigure, 'push');
            app.PlayAmpEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAmpEnvelopeButtonPushed, true);
            app.PlayAmpEnvelopeButton.Text = 'Play Amp Envelope';
            app.SustainSliderLabel = uilabel(app.UIFigure);
            app.SustainSliderLabel.Text = 'Sustain';
            app.SustainSlider = uislider(app.UIFigure);
            app.SustainSlider.Limits = [0 1];
            app.ReverbKnobLabel = uilabel(app.UIFigure);
            app.ReverbKnobLabel.Text = 'Reverb';
            app.ReverbKnob = uiknob(app.UIFigure, 'continuous');
            app.ReverbKnob.Limits = [0 1];
            app.ReverbKnob.ValueChangedFcn = createCallbackFcn(app, @ReverbKnobValueChanged, true);
            app.ConvolutionButton = uibutton(app.UIFigure, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution';
            app.Input1DropDownLabel = uilabel(app.UIFigure);
            app.Input1DropDownLabel.Text = 'Input 1:';
            app.Input1DropDown = uidropdown(app.UIFigure);
            app.Input1DropDown.Items = {'Select', 'Rect', 'Sin', 'Triangle'};
            app.Input1DropDown.ValueChangedFcn = createCallbackFcn(app, @Input1DropDownValueChanged, true);
            app.Input1DropDown.Value = 'Select';
            app.Input2DropDownLabel = uilabel(app.UIFigure);
            app.Input2DropDownLabel.Text = 'Input 2:';
            app.Input2DropDown = uidropdown(app.UIFigure);
            app.Input2DropDown.Items = {'Select', 'Rect', 'Sin', 'Triangle'};
            app.Input2DropDown.ValueChangedFcn = createCallbackFcn(app, @Input2DropDownValueChanged, true);
            app.Input2DropDown.Value = 'Select';
            app.GenerateMelodiesButton = uibutton(app.UIFigure, 'push');
            app.GenerateMelodiesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMelodiesButtonPushed, true);
            app.GenerateMelodiesButton.Text = 'Generate Melodies';
            app.FadeInsEditFieldLabel = uilabel(app.UIFigure);
            app.FadeInsEditFieldLabel.Text = 'Fade In (s)';
            app.FadeInsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FadeInsEditField.Limits = [0 Inf];
            app.FadeInsEditField.ValueChangedFcn = createCallbackFcn(app, @FadeInsEditFieldValueChanged, true);
            app.FadeOutsEditFieldLabel = uilabel(app.UIFigure);
            app.FadeOutsEditFieldLabel.Text = 'Fade Out (s)';
            app.FadeOutsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FadeOutsEditField.Limits = [0 Inf];
            app.FadeOutsEditField.ValueChangedFcn = createCallbackFcn(app, @FadeOutsEditFieldValueChanged, true);
            app.AmplitudeModulationButton = uibutton(app.UIFigure, 'push');
            app.AmplitudeModulationButton.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeModulationButtonPushed, true);
            app.AmplitudeModulationButton.Text = 'Amplitude Modulation';
            app.AdditiveSynthesisButton = uibutton(app.UIFigure, 'push');
            app.AdditiveSynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @AdditiveSynthesisButtonPushed, true);
            app.AdditiveSynthesisButton.Text = 'Additive Synthesis';
            app.HeartWaveButton = uibutton(app.UIFigure, 'push');
            app.HeartWaveButton.ButtonPushedFcn = createCallbackFcn(app, @HeartWaveButtonPushed, true);
            app.HeartWaveButton.Text = 'Heart Wave';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end